classdef ExportDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportData                    matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        DataBrowserPanel              matlab.ui.container.Panel
        DataTable                     matlab.ui.control.Table
        AzimuthPositionDropDownLabel  matlab.ui.control.Label
        AzimuthPositionDropDown       matlab.ui.control.DropDown
        SelectAllCheckBox             matlab.ui.control.CheckBox
        RollPositionDropDownLabel     matlab.ui.control.Label
        RollPositionDropDown          matlab.ui.control.DropDown
        ExportDataLabel               matlab.ui.control.Label
        ToolTipLabel                  matlab.ui.control.Label
        ExportDataIcon                matlab.ui.control.Image
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        MainAppObj;
        
        AZDegIntervalObj;
        RollDegIntervalObj;
    end
    
    methods (Access = public)
        
        function populateDropDowns(app)
            for i = 2:length(app.MainAppObj.DataToWriteObj)
                app.AzimuthPositionDropDown.Items = {app.AzimuthPositionDropDown.Items{1:end} num2str(app.MainAppObj.DataToWriteObj{i}(1,2,1))};
            end
            
            for j = 1:size(app.MainAppObj.DataToWriteObj{2},3)
                app.RollPositionDropDown.Items = {app.RollPositionDropDown.Items{1:end} num2str(app.MainAppObj.DataToWriteObj{2}(1,3,j))};
            end
        end
        
        function populateDataTable(app,AZDegToShow,RollDegToShow)
            app.DataTable.Data = [false*ones(length(app.MainAppObj.DataToWriteObj{AZDegToShow}(:,:,RollDegToShow)),1) app.MainAppObj.DataToWriteObj{AZDegToShow}(:,:,RollDegToShow)];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp)
            %app.DataTable.Data = {false,'Frequency','Azimuth','Roll','S21'};
            app.DataTable.ColumnFormat = {'logical', 'short e', []};
            app.CallingAppObj = callingApp;
            app.MainAppObj = mainApp;
            
            AZStartPos = app.MainAppObj.DataToWriteObj{2}(1,2,1);
            AZEndPos = app.MainAppObj.DataToWriteObj{end}(1,2,1);
            app.AZDegIntervalObj = AZStartPos:((abs(AZStartPos)+abs(AZEndPos))/(length(app.MainAppObj.DataToWriteObj) - 2)):AZEndPos;
            
            RollStartPos = app.MainAppObj.DataToWriteObj{2}(1,3,1);
            RollEndPos = app.MainAppObj.DataToWriteObj{2}(1,3,end);
            app.RollDegIntervalObj = RollStartPos:((abs(RollStartPos)+abs(RollEndPos))/(size(app.MainAppObj.DataToWriteObj{2},3) - 1)):RollEndPos;
            
            populateDropDowns(app)
            populateDataTable(app,2,1)
            pause(0.25);
            
            app.MainAppObj.setAppOnTop(true,app.ExportData.Name);
            
            
        end

        % Close request function: ExportData
        function ExportDataCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: AzimuthPositionDropDown, 
        % RollPositionDropDown
        function AzimuthPositionDropDownValueChanged(app, event)
            AZValue = app.AzimuthPositionDropDown.Value;
            RollValue = app.RollPositionDropDown.Value;
            
            [~,indexOfAZDeg] = find(str2num(AZValue) == app.AZDegIntervalObj);
            [~,indexOfRollDeg] = find(str2num(RollValue) == app.RollDegIntervalObj);
            populateDataTable(app,indexOfAZDeg + 1, indexOfRollDeg)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportData = uifigure('Visible', 'off');
            app.ExportData.Name = 'Select Data to Export';
            app.ExportData.CloseRequestFcn = createCallbackFcn(app, @ExportDataCloseRequest, true);
            app.FileMenu = uimenu(app.ExportData);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.ExportData);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.ExportData);
            app.ViewMenu.Text = 'View';
            app.DataBrowserPanel = uipanel(app.ExportData);
            app.DataBrowserPanel.Title = 'Data Browser';
            app.DataTable = uitable(app.DataBrowserPanel);
            app.DataTable.ColumnName = {'Selection'; 'Frequency'; 'Azimuth Pos'; 'Roll Pos'; 'S21'};
            app.DataTable.ColumnEditable = [true false false false false];
            app.AzimuthPositionDropDownLabel = uilabel(app.DataBrowserPanel);
            app.AzimuthPositionDropDownLabel.Text = 'Azimuth Position';
            app.AzimuthPositionDropDown = uidropdown(app.DataBrowserPanel);
            app.AzimuthPositionDropDown.Items = {};
            app.AzimuthPositionDropDown.ValueChangedFcn = createCallbackFcn(app, @AzimuthPositionDropDownValueChanged, true);
            app.AzimuthPositionDropDown.Value = {};
            app.SelectAllCheckBox = uicheckbox(app.DataBrowserPanel);
            app.SelectAllCheckBox.Text = 'Select All';
            app.RollPositionDropDownLabel = uilabel(app.DataBrowserPanel);
            app.RollPositionDropDownLabel.Text = 'Roll Position';
            app.RollPositionDropDown = uidropdown(app.DataBrowserPanel);
            app.RollPositionDropDown.Items = {};
            app.RollPositionDropDown.ValueChangedFcn = createCallbackFcn(app, @AzimuthPositionDropDownValueChanged, true);
            app.RollPositionDropDown.Value = {};
            app.ExportDataLabel = uilabel(app.ExportData);
            app.ExportDataLabel.Text = 'Export Data';
            app.ToolTipLabel = uilabel(app.ExportData);
            app.ToolTipLabel.Text = 'Choose the type of data you want to export, and select the file location.';
            app.ExportDataIcon = uiimage(app.ExportData);
            app.ExportDataIcon.ImageSource = 'export-icon-1450x2057.png';
            % Show the figure after all components are created
            app.ExportData.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportDataApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportData)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportData)
        end
    end
end