classdef ExportDataOptionsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportOptionsApp           matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        ViewMenu                   matlab.ui.container.Menu
        ExportDataOptionsPanel     matlab.ui.container.Panel
        MakeaSelectionButtonGroup  matlab.ui.container.ButtonGroup
        ChooseHowButton            matlab.ui.control.RadioButton
        ViewResultsButton          matlab.ui.control.RadioButton
        SaveExitButton             matlab.ui.control.RadioButton
        NextButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        MainAppObj;
        ExportDataAppObj;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp)
            app.CallingAppObj = callingApp;
            app.MainAppObj = mainApp;
            pause(0.5);
            
            app.MainAppObj.setAppOnTop(true,app.ExportOptionsApp.Name);
            
        end

        % Close request function: ExportOptionsApp
        function ExportOptionsAppCloseRequest(app, event)
            
            
            delete(app)
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if (app.ChooseHowButton.Value)
            
                app.ExportDataAppObj = ExportDataApp(app,app.MainAppObj);
                app.MainAppObj.ExportDataAppObj = app.ExportDataAppObj;
                
                app.ExportOptionsAppCloseRequest(app);
            elseif (app.ViewResultsButton.Value)
                
            else
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportOptionsApp = uifigure('Visible', 'off');
            app.ExportOptionsApp.Name = 'Export Data Options';
            app.ExportOptionsApp.CloseRequestFcn = createCallbackFcn(app, @ExportOptionsAppCloseRequest, true);
            app.FileMenu = uimenu(app.ExportOptionsApp);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.ExportOptionsApp);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.ExportOptionsApp);
            app.ViewMenu.Text = 'View';
            app.ExportDataOptionsPanel = uipanel(app.ExportOptionsApp);
            app.ExportDataOptionsPanel.Title = 'Export Data Options';
            app.MakeaSelectionButtonGroup = uibuttongroup(app.ExportDataOptionsPanel);
            app.MakeaSelectionButtonGroup.Title = 'Make a Selection';
            app.ChooseHowButton = uiradiobutton(app.MakeaSelectionButtonGroup);
            app.ChooseHowButton.Text = 'Choose How to Export Data';
            app.ChooseHowButton.Value = true;
            app.ViewResultsButton = uiradiobutton(app.MakeaSelectionButtonGroup);
            app.ViewResultsButton.Text = 'View Results in Measurement Plotter';
            app.SaveExitButton = uiradiobutton(app.MakeaSelectionButtonGroup);
            app.SaveExitButton.Text = 'Save & Exit';
            app.NextButton = uibutton(app.MakeaSelectionButtonGroup, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.ExportOptionsApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportDataOptionsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportOptionsApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportOptionsApp)
        end
    end
end