classdef PlotDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotData                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        DataPlotsPanel                matlab.ui.container.Panel
        FreqPlotAxes                  matlab.ui.control.UIAxes
        AZPositionDropDownLabel       matlab.ui.control.Label
        AZPositionDropDown            matlab.ui.control.DropDown
        RollPositionDropDownLabel     matlab.ui.control.Label
        RollPositionDropDown          matlab.ui.control.DropDown
        AZRollPlots                   matlab.ui.container.Panel
        AZPlotAxes                    matlab.ui.control.UIAxes
        RollPlotAxes                  matlab.ui.control.UIAxes
        FrequencyDropDownLabel        matlab.ui.control.Label
        FrequencyDropDown             matlab.ui.control.DropDown
        RollPositionDropDown_2Label   matlab.ui.control.Label
        RollPositionDropDown_2        matlab.ui.control.DropDown
        FrequencyDropDown_2Label      matlab.ui.control.Label
        FrequencyDropDown_2           matlab.ui.control.DropDown
        AzimuthPositionDropDownLabel  matlab.ui.control.Label
        AzimuthPositionDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        %DataObj{cellIDX}{:,1,:) = Frequency Value
        %DataObj{cellIDX}{:,2,:) = AZ Pos Value
        %DataObj{cellIDX}{:,3,:) = Roll Pos Value
        %DataObj{cellIDX}{:,4,:) = S21 Value
        
        CallingAppObj; % Description
        
        DataFileObj;
        DataObj;
    end
    
    methods (Access = public)
        
        function updateFrequencyPlot(app)
            
        end
        
        function updateAZPlot(app)
            
        end
        
        function updateRollPlot(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
         app.CallingAppObj = callingApp;
         pause(0.5);
         app.CallingAppObj.setAppOnTop(true,app.PlotData.Name);
         
         app.DataFileObj = app.CallingAppObj.ProjectFileObj;
         app.DataObj = readtable(app.DataFileObj);
         
         updateFrequencyPlot(app);
         updateAZPlot(app);
         updateRollPlot(app);
        end

        % Close request function: PlotData
        function PlotDataCloseRequest(app, event)
            app.CallingAppObj.PlotDataButton.Enable = 'on';
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotData = uifigure('Visible', 'off');
            app.PlotData.Name = 'UI Figure';
            app.PlotData.CloseRequestFcn = createCallbackFcn(app, @PlotDataCloseRequest, true);
            app.FileMenu = uimenu(app.PlotData);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.EditMenu = uimenu(app.PlotData);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.PlotData);
            app.ViewMenu.Text = 'View';
            app.DataPlotsPanel = uipanel(app.PlotData);
            app.DataPlotsPanel.Title = 'Data & Plots';
            app.FreqPlotAxes = uiaxes(app.DataPlotsPanel);
            title(app.FreqPlotAxes, 'Freqency Span vs. Gain')
            xlabel(app.FreqPlotAxes, 'Frequency (MHz)')
            ylabel(app.FreqPlotAxes, 'Gain (dBm)')
            app.AZPositionDropDownLabel = uilabel(app.DataPlotsPanel);
            app.AZPositionDropDownLabel.Text = 'AZ Position';
            app.AZPositionDropDown = uidropdown(app.DataPlotsPanel);
            app.RollPositionDropDownLabel = uilabel(app.DataPlotsPanel);
            app.RollPositionDropDownLabel.Text = 'Roll Position';
            app.RollPositionDropDown = uidropdown(app.DataPlotsPanel);
            app.AZRollPlots = uipanel(app.DataPlotsPanel);
            app.AZRollPlots.Title = 'AZ / Roll Plots';
            app.AZPlotAxes = uiaxes(app.AZRollPlots);
            title(app.AZPlotAxes, 'AZ Angle vs. Gain')
            xlabel(app.AZPlotAxes, 'Azimuth Angle (Degrees)')
            ylabel(app.AZPlotAxes, 'Gain (dBm)')
            app.RollPlotAxes = uiaxes(app.AZRollPlots);
            title(app.RollPlotAxes, 'Roll Angle vs. Gain')
            xlabel(app.RollPlotAxes, 'Roll Angle (Degrees)')
            ylabel(app.RollPlotAxes, 'Gain (dBm)')
            app.FrequencyDropDownLabel = uilabel(app.AZRollPlots);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.AZRollPlots);
            app.RollPositionDropDown_2Label = uilabel(app.AZRollPlots);
            app.RollPositionDropDown_2Label.Text = 'Roll Position';
            app.RollPositionDropDown_2 = uidropdown(app.AZRollPlots);
            app.FrequencyDropDown_2Label = uilabel(app.AZRollPlots);
            app.FrequencyDropDown_2Label.Text = 'Frequency';
            app.FrequencyDropDown_2 = uidropdown(app.AZRollPlots);
            app.AzimuthPositionDropDownLabel = uilabel(app.AZRollPlots);
            app.AzimuthPositionDropDownLabel.Text = 'Azimuth Position';
            app.AzimuthPositionDropDown = uidropdown(app.AZRollPlots);
            % Show the figure after all components are created
            app.PlotData.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotDataApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotData)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotData)
        end
    end
end